classdef etc_render_fsbrain_overlay_export < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ExportoverlayUIFigure         matlab.ui.Figure
        ExportoverlayPanel            matlab.ui.container.Panel
        OutputfilestemEditField       matlab.ui.control.EditField
        OutputfilestemEditFieldLabel  matlab.ui.control.Label
        OverlayDropDown               matlab.ui.control.DropDown
        OverlayDropDownLabel          matlab.ui.control.Label
        ChcekButton                   matlab.ui.control.Button
        SaveButton                    matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global etc_render_fsbrain


            app.SaveButton.Enable='off';
            app.OutputfilestemEditField.Enable='off';
            app.OverlayDropDown.Enable='off';

            if(isfield(etc_render_fsbrain,'overlay_source'))
                if(~isempty(etc_render_fsbrain.overlay_source))
                    if(etc_render_fsbrain.overlay_source>=1&etc_render_fsbrain.overlay_source<=4)
                        flag_ok=0;
                        switch etc_render_fsbrain.overlay_source
                            case 1
                                app.OverlayDropDown.Value=app.OverlayDropDown.Items{3}; %overlay_value
                                flag_ok=1;
                            case 2
                                app.OverlayDropDown.Value=app.OverlayDropDown.Items{2}; %overlay_vol_stc
                                flag_ok=1;
                            case 3
                                app.OverlayDropDown.Value=app.OverlayDropDown.Items{1}; %overlay_stc
                                flag_ok=1;
                            case 4
                                app.OverlayDropDown.Value=app.OverlayDropDown.Items{4}; %overlay_vol
                                flag_ok=1;
                        end;
                        

                        if(flag_ok)
                            app.SaveButton.Enable='on';
                            app.OutputfilestemEditField.Enable='on';
                            app.OverlayDropDown.Enable='on';
                        end;
                    end;
                end;
            end;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ExportoverlayUIFigure = uifigure('Visible', 'off');
            app.ExportoverlayUIFigure.Name = 'Export overlay';
            app.SaveButton = uibutton(app.ExportoverlayUIFigure, 'push');
            app.SaveButton.Text = 'Save';
            app.ExportoverlayPanel = uipanel(app.ExportoverlayUIFigure);
            app.ExportoverlayPanel.Title = 'Export overlay';
            app.ChcekButton = uibutton(app.ExportoverlayPanel, 'push');
            app.ChcekButton.Text = 'Chcek';
            app.OverlayDropDownLabel = uilabel(app.ExportoverlayPanel);
            app.OverlayDropDownLabel.Text = 'Overlay';
            app.OverlayDropDown = uidropdown(app.ExportoverlayPanel);
            app.OverlayDropDown.Items = {'space-time surface (STC)', 'space-time volume ', 'space surface (w)', 'space volume '};
            app.OverlayDropDown.Value = 'space-time surface (STC)';
            app.OutputfilestemEditFieldLabel = uilabel(app.ExportoverlayPanel);
            app.OutputfilestemEditFieldLabel.Text = 'Output file stem';
            app.OutputfilestemEditField = uieditfield(app.ExportoverlayPanel, 'text');
            % Show the figure after all components are created
            app.ExportoverlayUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = etc_render_fsbrain_overlay_export

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ExportoverlayUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ExportoverlayUIFigure)
        end
    end
end