classdef etc_render_fsbrain_tms_nav < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TMSNavFigure               matlab.ui.Figure
        EfieldPanel                matlab.ui.container.Panel
        TissueAddButton            matlab.ui.control.Button
        UITissueTable              matlab.ui.control.Table
        HeadModelDropDown          matlab.ui.control.DropDown
        headmodelLabel             matlab.ui.control.Label
        PrepModelLamp              matlab.ui.control.Lamp
        prepareheadmodelButton     matlab.ui.control.Button
        MakeModelLamp              matlab.ui.control.Lamp
        CalcButton                 matlab.ui.control.Button
        TextArea                   matlab.ui.control.TextArea
        ImportButton               matlab.ui.control.Button
        ExportButton               matlab.ui.control.Button
        StrcoilPanel               matlab.ui.container.Panel
        UpdatestrcoilButton        matlab.ui.control.Button
        StrcoilShowCheckBox        matlab.ui.control.CheckBox
        StrcoilLamp                matlab.ui.control.Lamp
        LoadstrcoilButton          matlab.ui.control.Button
        TMStargetPanel             matlab.ui.container.Panel
        rotatedegEditField         matlab.ui.control.NumericEditField
        offsetmmEditField          matlab.ui.control.NumericEditField
        horizdegEditField          matlab.ui.control.NumericEditField
        vertdegEditField           matlab.ui.control.NumericEditField
        CoilShowCheckBox           matlab.ui.control.CheckBox
        rotatedegSlider            matlab.ui.control.Slider
        rotatedegSliderLabel       matlab.ui.control.Label
        offsetmmSlider             matlab.ui.control.Slider
        offsetmmSliderLabel        matlab.ui.control.Label
        tilthorizdegSlider         matlab.ui.control.Slider
        tilthorizdegSliderLabel    matlab.ui.control.Label
        tiltvertdegSlider          matlab.ui.control.Slider
        tiltvertdegSliderLabel     matlab.ui.control.Label
        VecUpCheckBox              matlab.ui.control.CheckBox
        VecNormCheckBox            matlab.ui.control.CheckBox
        CoilLamp                   matlab.ui.control.Lamp
        xButton                    matlab.ui.control.Button
        LoadcoilButton             matlab.ui.control.Button
        vertexindexEditField       matlab.ui.control.EditField
        vertexindexEditFieldLabel  matlab.ui.control.Label
        XYZEditField               matlab.ui.control.EditField
        XYZEditFieldLabel          matlab.ui.control.Label
        CRSEditField               matlab.ui.control.EditField
        CRSEditFieldLabel          matlab.ui.control.Label
        MNIEditField               matlab.ui.control.EditField
        MNIEditField_2Label        matlab.ui.control.Label
        GoButton                   matlab.ui.control.Button
        ScalpPanel                 matlab.ui.container.Panel
        SButton                    matlab.ui.control.Button
        SurfaceDropDown            matlab.ui.control.DropDown
        surfaceDropDownLabel       matlab.ui.control.Label
        ShowscalpCheckBox          matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        strcoil_obj=[]; % Description
    end
    
    properties (Access = public)
        flag_show_up=0;
        flag_show_norm=0;
        up_obj=[]; % Description
        norm_obj=[]; % Description

        coil_center_no_tune=[]; %TMS coil center before any tuning

        strcoil_obj_xfm=eye(4); %transformation matrix at the moment when strcoil is loaded.
    end
    
    methods (Access = private)
        
        function results = tune_vert(app)
            value = app.tiltvertdegSlider.Value;

            global etc_render_fsbrain;


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %define the target in the brain
            click_idx= str2num(app.vertexindexEditField.Value); %<------define target indexx
            target=etc_render_fsbrain.vertex_coords(click_idx,:);
            

            R_c=eye(4);
            R_c_mm=eye(4);
            R_c(1:3,4)=-target(1:3)./1e3;
            R_c_mm(1:3,4)=-target(1:3);

            %coil_axis=etc_render_fsbrain.object.UserData.Axis(:).';
            coil_axis=cross(etc_render_fsbrain.object.UserData.Axis(:),etc_render_fsbrain.object.UserData.Up(:));
            tmp=coil_axis./norm(coil_axis);
            x=tmp(1);
            y=tmp(2);
            z=tmp(3);

            
            if(isempty(app.coil_center_no_tune)) return; end; %taget not first defined by default

            v1=app.coil_center_no_tune(:)-target(:);
            v2=etc_render_fsbrain.object.UserData.Origin(:)-target(:);

            deg = atan2(norm(cross(v2,v1)), dot(v2,v1));
            if(dot(coil_axis,cross(v1,v2))>0) deg=-deg; end;
            %fprintf('coil now is %1.0f (deg) away from untuned orientation.\n',deg*180/pi);

            %determine the rotation between two vectors;
            theta=value./180*pi-deg;
            %theta=value.*pi./180-deg;
            %fprintf('additional %1.0f (deg) rotation from untuned orientation is requested.\n',theta*180/pi);

            r_rotz=[cos(theta)+x*x*(1-cos(theta)), x*y*(1-cos(theta))-z*sin(theta), x*z*(1-cos(theta))+y*sin(theta);
                y*x*(1-cos(theta))+z*sin(theta) cos(theta)+y*y*(1-cos(theta)) y*z*(1-cos(theta))-x*sin(theta);
                z*x*(1-cos(theta))-y*sin(theta) z*y*(1-cos(theta))+x*sin(theta) cos(theta)+z*z*(1-cos(theta))];
            %https://en.wikipedia.org/wiki/Rotation_matrix
            R_rotz=eye(4);
            R_rotz(1:3,1:3)=r_rotz;


            etc_render_fsbrain.object_xfm=inv(R_c)*inv(R_rotz)*(R_c)*etc_render_fsbrain.object_xfm;

            %transform object in visualiation

            vv=etc_render_fsbrain.object.Vertices;
            vv(:,4)=1;
            vv=(inv(R_c_mm)*inv(R_rotz)*(R_c_mm)*vv')';
            etc_render_fsbrain.object.Vertices=vv(:,1:3);


            if(~isempty(app.norm_obj))
                tmp=[app.norm_obj.XData; app.norm_obj.YData; app.norm_obj.ZData; ones(size(app.norm_obj.XData))];
                tmp=((inv(R_c_mm)*inv(R_rotz)*R_c_mm)*tmp);
                %tmp=((inv(R_c)*inv(R_rotz)*R_c)*R_trans_mm*R_tx_now*inv(R_rot)*inv(R_tx_now)*tmp);
                app.norm_obj.XData=tmp(1,:);
                app.norm_obj.YData=tmp(2,:);
                app.norm_obj.ZData=tmp(3,:);
            end;
            
            if(~isempty(app.up_obj))
                tmp=[app.up_obj.XData; app.up_obj.YData; app.up_obj.ZData; ones(size(app.up_obj.XData))];
                tmp=((inv(R_c_mm)*inv(R_rotz)*R_c_mm)*tmp);
                %tmp=((inv(R_c)*inv(R_rotz)*R_c)*R_trans_mm*R_tx_now*inv(R_rot)*inv(R_tx_now)*tmp);
                app.up_obj.XData=tmp(1,:);
                app.up_obj.YData=tmp(2,:);
                app.up_obj.ZData=tmp(3,:);
            end;


            %transform data
            tmp=((inv(R_c_mm)*inv(R_rotz)*R_c_mm)*[etc_render_fsbrain.object.UserData.Origin(:)' 1]')';
            etc_render_fsbrain.object.UserData.Origin=tmp(1:3);

            tmp=(inv(R_rotz)*[etc_render_fsbrain.object.UserData.Axis(:)' 1]')';
            etc_render_fsbrain.object.UserData.Axis=tmp(1:3);

            tmp=(inv(R_rotz)*[etc_render_fsbrain.object.UserData.Up(:)' 1]')';
            etc_render_fsbrain.object.UserData.Up=tmp(1:3);


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            v1=app.coil_center_no_tune(:)-target(:);
            v2=etc_render_fsbrain.object.UserData.Origin(:)-target(:);
            deg = atan2(norm(cross(v2,v1)), dot(v2,v1));
            if(dot(coil_axis,cross(v1,v2))>0) deg=-deg; end;
            %fprintf('Finally, coil now is %1.0f (deg) away from untuned orientation.\n',deg*180/pi);

        end
        
        function results = tune_horiz(app)
            value = app.tilthorizdegSlider.Value;
                       
            global etc_render_fsbrain;


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %define the target in the brain
            click_idx= str2num(app.vertexindexEditField.Value); %<------define target indexx
            target=etc_render_fsbrain.vertex_coords(click_idx,:);


            R_c=eye(4);
            R_c_mm=eye(4);
            R_c(1:3,4)=-target(1:3)./1e3;
            R_c_mm(1:3,4)=-target(1:3);

            %coil_axis=etc_render_fsbrain.object.UserData.Axis(:).';
            coil_axis=etc_render_fsbrain.object.UserData.Up(:);
            tmp=coil_axis./norm(coil_axis);
            x=tmp(1);
            y=tmp(2);
            z=tmp(3);

            
            if(isempty(app.coil_center_no_tune)) return; end; %taget not first defined by default

            v1=app.coil_center_no_tune(:)-target(:);
            v2=etc_render_fsbrain.object.UserData.Origin(:)-target(:);

            deg = atan2(norm(cross(v2,v1)), dot(v2,v1));
            if(dot(coil_axis,cross(v1,v2))>0) deg=-deg; end;
            %fprintf('coil now is %1.0f (deg) away from untuned orientation.\n',deg*180/pi);

            %determine the rotation between two vectors;
            theta=value./180*pi-deg;
            %theta=value.*pi./180-deg;
            %fprintf('additional %1.0f (deg) rotation from untuned orientation is requested.\n',theta*180/pi);

            r_rotz=[cos(theta)+x*x*(1-cos(theta)), x*y*(1-cos(theta))-z*sin(theta), x*z*(1-cos(theta))+y*sin(theta);
                y*x*(1-cos(theta))+z*sin(theta) cos(theta)+y*y*(1-cos(theta)) y*z*(1-cos(theta))-x*sin(theta);
                z*x*(1-cos(theta))-y*sin(theta) z*y*(1-cos(theta))+x*sin(theta) cos(theta)+z*z*(1-cos(theta))];
            %https://en.wikipedia.org/wiki/Rotation_matrix
            R_rotz=eye(4);
            R_rotz(1:3,1:3)=r_rotz;


            etc_render_fsbrain.object_xfm=inv(R_c)*inv(R_rotz)*(R_c)*etc_render_fsbrain.object_xfm;

            %transform object in visualiation

            vv=etc_render_fsbrain.object.Vertices;
            vv(:,4)=1;
            vv=(inv(R_c_mm)*inv(R_rotz)*(R_c_mm)*vv')';
            etc_render_fsbrain.object.Vertices=vv(:,1:3);


            if(~isempty(app.norm_obj))
                tmp=[app.norm_obj.XData; app.norm_obj.YData; app.norm_obj.ZData; ones(size(app.norm_obj.XData))];
                tmp=((inv(R_c_mm)*inv(R_rotz)*R_c_mm)*tmp);
                %tmp=((inv(R_c)*inv(R_rotz)*R_c)*R_trans_mm*R_tx_now*inv(R_rot)*inv(R_tx_now)*tmp);
                app.norm_obj.XData=tmp(1,:);
                app.norm_obj.YData=tmp(2,:);
                app.norm_obj.ZData=tmp(3,:);
            end;
            
            if(~isempty(app.up_obj))
                tmp=[app.up_obj.XData; app.up_obj.YData; app.up_obj.ZData; ones(size(app.up_obj.XData))];
                tmp=((inv(R_c_mm)*inv(R_rotz)*R_c_mm)*tmp);
                %tmp=((inv(R_c)*inv(R_rotz)*R_c)*R_trans_mm*R_tx_now*inv(R_rot)*inv(R_tx_now)*tmp);
                app.up_obj.XData=tmp(1,:);
                app.up_obj.YData=tmp(2,:);
                app.up_obj.ZData=tmp(3,:);
            end;


            %transform data
            tmp=((inv(R_c_mm)*inv(R_rotz)*R_c_mm)*[etc_render_fsbrain.object.UserData.Origin(:)' 1]')';
            etc_render_fsbrain.object.UserData.Origin=tmp(1:3);

            tmp=(inv(R_rotz)*[etc_render_fsbrain.object.UserData.Axis(:)' 1]')';
            etc_render_fsbrain.object.UserData.Axis=tmp(1:3);

            tmp=(inv(R_rotz)*[etc_render_fsbrain.object.UserData.Up(:)' 1]')';
            etc_render_fsbrain.object.UserData.Up=tmp(1:3);


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            v1=app.coil_center_no_tune(:)-target(:);
            v2=etc_render_fsbrain.object.UserData.Origin(:)-target(:);
            deg = atan2(norm(cross(v2,v1)), dot(v2,v1));
            if(dot(coil_axis,cross(v1,v2))>0) deg=-deg; end;
            %fprintf('Finally, coil now is %1.0f (deg) away from untuned orientation.\n',deg*180/pi);
        end
        
        function results = tune_offset(app)
            value = app.offsetmmSlider.Value;

            global etc_render_fsbrain;

            if(isempty(app.coil_center_no_tune)) return; end; %taget not first defined by default


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %define the target in the brain
            target=app.coil_center_no_tune;         


            R_c=eye(4);
            R_c_mm=eye(4);
            R_c(1:3,4)=-target(1:3)./1e3;
            R_c_mm(1:3,4)=-target(1:3);

            coil_axis=etc_render_fsbrain.object.UserData.Axis(:).';
            tmp=coil_axis./norm(coil_axis);
            

            v2=norm(etc_render_fsbrain.object.UserData.Origin(:)-target(:));
            tmp=(value-v2).*tmp;

            R_offset=eye(4);
            R_offset_mm=eye(4);
            R_offset(1:3,4)=-tmp(:)./1e3;
            R_offset_mm(1:3,4)=-tmp(:);


            etc_render_fsbrain.object_xfm=R_offset*etc_render_fsbrain.object_xfm;

            %transform object in visualiation

            vv=etc_render_fsbrain.object.Vertices;
            vv(:,4)=1;
            vv=(R_offset_mm*vv')';
            etc_render_fsbrain.object.Vertices=vv(:,1:3);


            if(~isempty(app.norm_obj))
                tmp=[app.norm_obj.XData; app.norm_obj.YData; app.norm_obj.ZData; ones(size(app.norm_obj.XData))];
                tmp=(R_offset_mm*tmp);
                app.norm_obj.XData=tmp(1,:);
                app.norm_obj.YData=tmp(2,:);
                app.norm_obj.ZData=tmp(3,:);
            end;
            
            if(~isempty(app.up_obj))
                tmp=[app.up_obj.XData; app.up_obj.YData; app.up_obj.ZData; ones(size(app.up_obj.XData))];
                tmp=(R_offset_mm*tmp);
                app.up_obj.XData=tmp(1,:);
                app.up_obj.YData=tmp(2,:);
                app.up_obj.ZData=tmp(3,:);
            end;


            %transform data
            tmp=(R_offset_mm*[etc_render_fsbrain.object.UserData.Origin(:)' 1]')';
            etc_render_fsbrain.object.UserData.Origin=tmp(1:3);

            tmp=(eye(4)*[etc_render_fsbrain.object.UserData.Axis(:)' 1]')';
            etc_render_fsbrain.object.UserData.Axis=tmp(1:3);

            tmp=(eye(4)*[etc_render_fsbrain.object.UserData.Up(:)' 1]')';
            etc_render_fsbrain.object.UserData.Up=tmp(1:3);
        end
        
        function results = tune_rotate(app)
            value = app.rotatedegSlider.Value;


            global etc_render_fsbrain;

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %aligning UP toward +z

            coil_center=etc_render_fsbrain.object.UserData.Origin(:);
            R_c_mm=eye(4);
            R_c=eye(4);
            R_c_mm(1:3,4)=-coil_center(1:3);
            R_c(1:3,4)=-coil_center(1:3)./1e3;
            
            coil_axis=etc_render_fsbrain.object.UserData.Axis(:).';
            tmp=coil_axis./norm(coil_axis);
            x=tmp(1);
            y=tmp(2);
            z=tmp(3);

            X1=etc_render_fsbrain.object.UserData.Origin(1);
            Y1=etc_render_fsbrain.object.UserData.Origin(2);
            Z1=etc_render_fsbrain.object.UserData.Origin(3);

            X2=0;
            Y2=0;
            Z2=100;
            vtop=[X2-X1; Y2-Y1; Z2-Z1]';
            vtop_perp=vtop-sum(vtop.*tmp).*tmp;
           
            vup=etc_render_fsbrain.object.UserData.Up;


            %determine the rotation between two vectors;
            xx = cross(vtop_perp,vup);
            c = sign(dot(xx,etc_render_fsbrain.object.UserData.Axis)) * norm(xx);
            deg = atan2d(c,dot(vtop_perp,vup)).*pi./180;
            theta=(deg-value./180*pi);

            r_rotz=[cos(theta)+x*x*(1-cos(theta)), x*y*(1-cos(theta))-z*sin(theta), x*z*(1-cos(theta))+y*sin(theta);
                y*x*(1-cos(theta))+z*sin(theta) cos(theta)+y*y*(1-cos(theta)) y*z*(1-cos(theta))-x*sin(theta);
                z*x*(1-cos(theta))-y*sin(theta) z*y*(1-cos(theta))+x*sin(theta) cos(theta)+z*z*(1-cos(theta))];
            %https://en.wikipedia.org/wiki/Rotation_matrix
            R_rotz=eye(4);
            R_rotz(1:3,1:3)=r_rotz;


            etc_render_fsbrain.object_xfm=inv(R_c)*inv(R_rotz)*(R_c)*etc_render_fsbrain.object_xfm;

            %transform object in visualiation

            vv=etc_render_fsbrain.object.Vertices;
            vv(:,4)=1;
            vv=(inv(R_c_mm)*inv(R_rotz)*(R_c_mm)*vv')';
            etc_render_fsbrain.object.Vertices=vv(:,1:3);


            if(~isempty(app.norm_obj))
                tmp=[app.norm_obj.XData; app.norm_obj.YData; app.norm_obj.ZData; ones(size(app.norm_obj.XData))];
                tmp=((inv(R_c_mm)*inv(R_rotz)*R_c_mm)*tmp);
                %tmp=((inv(R_c)*inv(R_rotz)*R_c)*R_trans_mm*R_tx_now*inv(R_rot)*inv(R_tx_now)*tmp);
                app.norm_obj.XData=tmp(1,:);
                app.norm_obj.YData=tmp(2,:);
                app.norm_obj.ZData=tmp(3,:);
            end;
            
            if(~isempty(app.up_obj))
                tmp=[app.up_obj.XData; app.up_obj.YData; app.up_obj.ZData; ones(size(app.up_obj.XData))];
                tmp=((inv(R_c_mm)*inv(R_rotz)*R_c_mm)*tmp);
                %tmp=((inv(R_c)*inv(R_rotz)*R_c)*R_trans_mm*R_tx_now*inv(R_rot)*inv(R_tx_now)*tmp);
                app.up_obj.XData=tmp(1,:);
                app.up_obj.YData=tmp(2,:);
                app.up_obj.ZData=tmp(3,:);
            end;


            %transform data
            tmp=((inv(R_c_mm)*inv(R_rotz)*R_c_mm)*[etc_render_fsbrain.object.UserData.Origin(:)' 1]')';
            %tmp=((inv(R_c)*inv(R_rotz)*(R_c))*R_trans_mm*R_tx_now*inv(R_rot)*inv(R_tx_now)*[etc_render_fsbrain.object.UserData.Origin(:)' 1]')';
            etc_render_fsbrain.object.UserData.Origin=tmp(1:3);

            tmp=(inv(R_rotz)*[etc_render_fsbrain.object.UserData.Axis(:)' 1]')';
            %tmp=((inv(R_c)*inv(R_rotz)*(R_c))*R_trans_mm*R_tx_now*inv(R_rot)*inv(R_tx_now)*[etc_render_fsbrain.object.UserData.Axis(:)' 1]')';
            etc_render_fsbrain.object.UserData.Axis=tmp(1:3);

            tmp=(inv(R_rotz)*[etc_render_fsbrain.object.UserData.Up(:)' 1]')';
            %tmp=((inv(R_c)*inv(R_rotz)*(R_c))*R_trans_mm*R_tx_now*inv(R_rot)*inv(R_tx_now)*[etc_render_fsbrain.object.UserData.Up(:)' 1]')';
            etc_render_fsbrain.object.UserData.Up=tmp(1:3);


            %end of aligning UP toward +z
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global etc_render_fsbrain;

            app.SurfaceDropDown.Items={};
            if(isfield(etc_render_fsbrain,'surf_obj'))
                for idx=1:length(etc_render_fsbrain.surf_obj)
                    [pp,ff]=fileparts(etc_render_fsbrain.surf_obj(idx).filename);
                    app.SurfaceDropDown.Items{idx}=ff;
                    app.SurfaceDropDown.Value=ff;
                    if(isfield(etc_render_fsbrain.surf_obj(idx),'patch_handle'))
                        if(~isvalid(etc_render_fsbrain.surf_obj(idx).patch_handle))
                            [vv,ff]=read_surf(etc_render_fsbrain.surf_obj(idx).filename);

                            etc_render_fsbrain.surf_obj(idx).patch_handle=patch('vertices',vv,'faces',ff+1,'edgecolor','none','facecolor',etc_render_fsbrain.surf_obj(idx).color,'facealpha',0.2); %show scalp

                        end;
                    end;
                end;
                if(isempty(app.SurfaceDropDown.Items))
                    app.ShowscalpCheckBox.Enable='off';
                else
                    app.ShowscalpCheckBox.Enable='on';
                end;
            else
                app.ShowscalpCheckBox.Enable='off';
            end;

            app.ShowscalpCheckBox.Value=0;
            if(isfield(etc_render_fsbrain,'surf_obj'))
                for idx=1:length(etc_render_fsbrain.surf_obj)
                    if(isfield(etc_render_fsbrain.surf_obj(idx),'patch_handle'))
                        if(isvalid(etc_render_fsbrain.surf_obj(idx).patch_handle))
                            if(etc_render_fsbrain.surf_obj(idx).patch_handle.Visible)
                                app.ShowscalpCheckBox.Value=1;
                            end;
                        end;
                    end;
                end;
            end;

            app.CoilLamp.Color='r';
            app.CoilShowCheckBox.Value=0;
            if(isfield(etc_render_fsbrain,'object'))
                if(~isempty(etc_render_fsbrain.object)) %coil patch exists
                    if(isfield(etc_render_fsbrain.object,'Vertices'))
                        app.CoilLamp.Color='g';
                    end;
                    if(isfield(etc_render_fsbrain.object,'Visible'))
                        if(strcmp(etc_render_fsbrain.object.Visible,'on'))
                            app.CoilShowCheckBox.Value=1;
                        end;
                    end;
                end;
            end;


            if(isfield(etc_render_fsbrain,'click_coord'))
                if(~isempty(etc_render_fsbrain.click_coord))

                    vv=etc_render_fsbrain.vertex_coords;
                    dist=sqrt(sum((vv-repmat([etc_render_fsbrain.click_coord(1),etc_render_fsbrain.click_coord(2),etc_render_fsbrain.click_coord(3)],[size(vv,1),1])).^2,2));
                    [min_dist,min_dist_idx]=min(dist);

                    app.vertexindexEditField.Value=num2str(min_dist_idx,'%1.0f ');

                    app.XYZEditField.Value=num2str(etc_render_fsbrain.click_coord(:)','%1.1f ');

                    app.CRSEditField.Value=num2str(etc_render_fsbrain.click_vertex_vox(:)','%1.0f ');

                    app.MNIEditField.Value=num2str(etc_render_fsbrain.click_vertex_point_tal(:)','%1.0f ');
                end;
            end;


            app.UITissueTable.ColumnName={'Tissue','Boundary MAT','cond.','Enclosing Tissue'};

            app.UITissueTable.ColumnEditable = [false true true true];
        end

        % Callback function
        function loadscalpButtonPushed(app, event)
            global etc_render_fsbrain;

            [filename, pathname, filterindex] = uigetfile({'*.*','surface'});
            if(filename==0) return; end;


            [vv,ff]=read_surf(sprintf('%s/%s',pathname, filename));

            %geometrical information about scalp
            TR = triangulation(ff+1,vv);
            surf_center= incenter(TR);
            surf_norm = faceNormal(TR);


            [eS, TriPS, TriMS] = mt(TR);
            if(~isfield(etc_render_fsbrain,'surf_obj')) etc_render_fsbrain.surf_obj=[]; end;
            etc_render_fsbrain.surf_obj(end+1).vertex=vv;
            etc_render_fsbrain.surf_obj(end).face=ff;
            etc_render_fsbrain.surf_obj(end).TR=TR;
            etc_render_fsbrain.surf_obj(end).surf_center=surf_center;
            etc_render_fsbrain.surf_obj(end).surf_norm=surf_norm;
            etc_render_fsbrain.surf_obj(end).eS=eS;
            etc_render_fsbrain.surf_obj(end).TriPS=TriPS;
            etc_render_fsbrain.surf_obj(end).TriMS=TriMS;
            etc_render_fsbrain.surf_obj(end).color=app.ScalpColorButton.BackgroundColor;
            etc_render_fsbrain.surf_obj(end).flag_show=1;
            etc_render_fsbrain.surf_obj(end).filename=sprintf('%s/%s',pathname, filename);

            app.ShowscalpCheckBox.Value=1;

            etc_render_fsbrain.surf_obj(end).patch_handle=patch('vertices',vv,'faces',ff+1,'edgecolor','none','facecolor',etc_render_fsbrain.surf_obj(end).color,'facealpha',0.2); %show scalp
            
            axis vis3d tight;

            etc_render_fsbrain_handle('draw_pointer');

        end

        % Callback function
        function ScalpColorButtonPushed(app, event)
            c = uisetcolor;
            if(length(c)>1)
                app.ScalpColorButton.BackgroundColor=c;

                global etc_render_fsbrain;
                etc_render_fsbrain.surf_obj(end).color=c;
                if(isfield(etc_render_fsbrain.surf_obj(end),'patch_handle'))
                    etc_render_fsbrain.surf_obj(end).patch_handle.FaceColor=c;
                end;
                etc_render_fsbrain_handle('draw_pointer');
            end;
        end

        % Value changed function: ShowscalpCheckBox
        function ShowscalpCheckBoxValueChanged(app, event)
            value = app.ShowscalpCheckBox.Value;

            global etc_render_fsbrain;

            surface_list={};
            for surf_idx=1:length(etc_render_fsbrain.surf_obj)
                [pp,ff]=fileparts(etc_render_fsbrain.surf_obj(surf_idx).filename);
                surface_list{end+1}=ff;
            end;
            [a,b]=ismember(app.SurfaceDropDown.Value,surface_list); %determine which surface is selected

            if(a<eps) return; end; %no matched surface!

            if(value)
                if(isfield(etc_render_fsbrain.surf_obj(end),'patch_handle'))
                    set(etc_render_fsbrain.surf_obj(b).patch_handle,'visible','on');
                end;
            else
                if(isfield(etc_render_fsbrain.surf_obj(end),'patch_handle'))
                    set(etc_render_fsbrain.surf_obj(b).patch_handle,'visible','off');
                end;                
            end;
        end

        % Close request function: TMSNavFigure
        function TMSNavFigureCloseRequest(app, event)
            
            if(~isempty(app.strcoil_obj))
                delete(app.strcoil_obj);
                app.strcoil_obj=[];
            end;

            try
                delete(app.norm_obj);
            catch
            end;
            try
                delete(app.up_obj);
            catch
            end;
            try
                delete(app.scalp_obj);
            catch
            end;
            try
                delete(app)
            catch
            end;
            
        end

        % Value changed function: MNIEditField
        function MNIEditFieldValueChanged(app, event)
            value = app.MNIEditField.Value;
            
            global etc_render_fsbrain;

            if(~isempty(etc_render_fsbrain.click_vertex))
                try
                    coord= str2num(value);
                    mni=[coord 1]';
                    click_vertex_vox=inv(etc_render_fsbrain.vol.vox2ras)*inv(etc_render_fsbrain.vol_pre_xfm)*inv(etc_render_fsbrain.talxfm)*mni;
                    %click_vertex_vox=inv(etc_render_fsbrain.vol.tkrvox2ras)*inv(etc_render_fsbrain.vol_pre_xfm)*inv(etc_render_fsbrain.talxfm)*mni;
                    click_vertex_vox=click_vertex_vox(1:3)';

                    surface_coord=etc_render_fsbrain.vol.tkrvox2ras*[click_vertex_vox(:); 1];
                    surface_coord=surface_coord(1:3);

                    vv=etc_render_fsbrain.orig_vertex_coords;
                    dist=sqrt(sum((vv-repmat([surface_coord(1),surface_coord(2),surface_coord(3)],[size(vv,1),1])).^2,2));
                    [min_dist,min_dist_idx]=min(dist);
                    %surface_coord=etc_render_fsbrain.vertex_coords(min_dist_idx,:)';

                    etc_render_fsbrain_handle('draw_pointer','surface_coord',surface_coord,'min_dist_idx',min_dist_idx,'click_vertex_vox',click_vertex_vox);
                catch ME
                end;
            end;


        end

        % Value changed function: CRSEditField
        function CRSEditFieldValueChanged(app, event)
            value = app.CRSEditField.Value;
            
            global etc_render_fsbrain;

            if(~isempty(etc_render_fsbrain.click_vertex))
                try
                    coord= str2num(value);
                    click_vertex_vox=[coord];
                    tmp=[click_vertex_vox 1]';
                    mni=etc_render_fsbrain.talxfm*etc_render_fsbrain.vol_pre_xfm*etc_render_fsbrain.vol.vox2ras*tmp;
                    %mni=etc_render_fsbrain.talxfm*etc_render_fsbrain.vol_pre_xfm*etc_render_fsbrain.vol.tkrvox2ras*tmp;
                    mni=min(1:3)';

                    surface_coord=etc_render_fsbrain.vol.tkrvox2ras*[click_vertex_vox(:); 1];
                    surface_coord=surface_coord(1:3);

                    vv=etc_render_fsbrain.orig_vertex_coords;
                    dist=sqrt(sum((vv-repmat([surface_coord(1),surface_coord(2),surface_coord(3)],[size(vv,1),1])).^2,2));
                    [min_dist,min_dist_idx]=min(dist);
                    %surface_coord=etc_render_fsbrain.vertex_coords(min_dist_idx,:)';

                    etc_render_fsbrain_handle('draw_pointer','surface_coord',surface_coord,'min_dist_idx',min_dist_idx,'click_vertex_vox',click_vertex_vox);
                catch ME
                end;
            end;

        end

        % Value changed function: XYZEditField
        function XYZEditFieldValueChanged(app, event)
            value = app.XYZEditField.Value;

            global etc_render_fsbrain;

            if(~isempty(etc_render_fsbrain.click_vertex))
                try
                    coord= str2num(value);

                    surface_coord=[coord];
                    tmp=[surface_coord 1]';
                    click_vertex_vox=inv(etc_render_fsbrain.vol.tkrvox2ras)*tmp;
                    click_vertex_vox=click_vertex_vox(1:3)';

                    vv=etc_render_fsbrain.orig_vertex_coords;
                    dist=sqrt(sum((vv-repmat([surface_coord(1),surface_coord(2),surface_coord(3)],[size(vv,1),1])).^2,2));
                    [min_dist,min_dist_idx]=min(dist);
                    %surface_coord=etc_render_fsbrain.vertex_coords(min_dist_idx,:)';

                    etc_render_fsbrain_handle('draw_pointer','surface_coord',surface_coord,'min_dist_idx',min_dist_idx,'click_vertex_vox',click_vertex_vox);
                catch ME
                end;
            end;

        end

        % Value changed function: vertexindexEditField
        function vertexindexEditFieldValueChanged(app, event)
            value = app.vertexindexEditField.Value;
                       
            global etc_render_fsbrain;

            if(~isempty(etc_render_fsbrain.click_vertex))
                try
                    coord_idx= str2num(value);
                    vv=etc_render_fsbrain.vertex_coords;
                    coord=vv(coord_idx,:);

                    surface_coord=[coord];
                    tmp=[surface_coord 1]';
                    click_vertex_vox=inv(etc_render_fsbrain.vol.tkrvox2ras)*tmp;
                    click_vertex_vox=click_vertex_vox(1:3)';

                    vv=etc_render_fsbrain.orig_vertex_coords;
                    dist=sqrt(sum((vv-repmat([surface_coord(1),surface_coord(2),surface_coord(3)],[size(vv,1),1])).^2,2));
                    [min_dist,min_dist_idx]=min(dist);
                    %surface_coord=etc_render_fsbrain.vertex_coords(min_dist_idx,:)';

                    etc_render_fsbrain_handle('draw_pointer','surface_coord',surface_coord,'min_dist_idx',min_dist_idx,'click_vertex_vox',click_vertex_vox);
                catch ME
                end;
            end;

            
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % TMS coil placement
            global etc_render_fsbrain;

            if(norm(app.CoilLamp.Color-[1 0 0])<eps) return; end;

            if(isempty(app.vertexindexEditField.Value)) return; end;

            %define the target in the brain
            click_idx= str2num(app.vertexindexEditField.Value); %<------define target indexx
            target=etc_render_fsbrain.vertex_coords(click_idx,:);
            

            surface_list={};
            for surf_idx=1:length(etc_render_fsbrain.surf_obj)
                [pp,ff]=fileparts(etc_render_fsbrain.surf_obj(surf_idx).filename);
                surface_list{end+1}=ff;
            end;
            [a,scalp_idx]=ismember(app.SurfaceDropDown.Value,surface_list); %determine which surface is selected

            if(a<eps) return; end; %no matched surface!

            surf_center=etc_render_fsbrain.surf_obj(scalp_idx).surf_center;
            surf_norm=etc_render_fsbrain.surf_obj(scalp_idx).surf_norm;

            %calculate target-coil distance
            dist=sqrt(sum(bsxfun(@minus,surf_center,target).^2,2));
            [min_dist,min_idx]=min(dist);

            %show coil position with the minimal distance
            %cc=get(gca,'colororder');
            %hq=quiver3(surf_center(min_idx,1),surf_center(min_idx,2),surf_center(min_idx,3),surf_norm(min_idx,1),surf_norm(min_idx,2),surf_norm(min_idx,3),15,'color','r');

            coil_center=surf_center(min_idx,:); % in mm
            coil_orientation=target-coil_center;
            fprintf('distance between the target and coil center = %2.2f (mm)\n',norm(coil_orientation));
            app.TextArea.Value{end+1}=sprintf('distance between the target and coil center = %2.2f (mm)\n',norm(coil_orientation));

            coil_orientation=coil_orientation./norm(coil_orientation);
            scalp_orientation=surf_norm(min_idx,:);

            % derive the rotation matrix between two vectors (from, to)
            % https://math.stackexchange.com/questions/180418/calculate-rotation-matrix-to-align-vector-a-to-vector-b-in-3d
            ssc = @(v) [0 -v(3) v(2); v(3) 0 -v(1); -v(2) v(1) 0];
            RU = @(A,B) eye(3) + ssc(cross(A,B)) + ssc(cross(A,B))^2*(1-dot(A,B))/(norm(cross(A,B))^2);

            %fprintf('before moving: coil origin: %s \n',mat2str(etc_render_fsbrain.object.UserData.Origin(:).',3));
            %fprintf('before moving: coil origin target: %s \n',mat2str(coil_center(:).',3));
            
            %etc_render_fsbrain.object.UserData.Axis(:).'

            coil_shift=-etc_render_fsbrain.object.UserData.Origin(:)+coil_center(:);
            n=norm(etc_render_fsbrain.object.UserData.Axis(:)'-coil_orientation);
            if(n>eps)
                RR=RU(etc_render_fsbrain.object.UserData.Axis(:)',coil_orientation(:)');
            else
                RR=eye(3);
            end;
            if(find(isnan(RR(:)))) keyboard; end;
            if(find(isnan(coil_shift(:)))) keyboard; end;

            % update object_xfm matrix
            R=eye(4);
            R(1:3,4)=etc_render_fsbrain.object.UserData.Origin(:);
            R_tx_now_mm=R;
            R_tx_now=R_tx_now_mm;
            R_tx_now(1:3,4)=R_tx_now(1:3,4)./1e3;
            

            R=eye(4);
            R(1:3,1:3)=RR;
            R_rot=R';

            R=eye(4);
            R(1:3,4)=coil_shift(:)./1e3; %in m
            R_trans=R;

            R=eye(4);
            R(1:3,4)=coil_shift(:); %in mm
            R_trans_mm=R;

            if(isempty(etc_render_fsbrain.object_xfm))
                etc_render_fsbrain.object_xfm=R_trans*R_tx_now*inv(R_rot)*inv(R_tx_now);
            else

                etc_render_fsbrain.object_xfm=R_trans*R_tx_now*inv(R_rot)*inv(R_tx_now)*etc_render_fsbrain.object_xfm;
            end;
            %transform object in visualiation
            vv=etc_render_fsbrain.object.Vertices;
            vv(:,4)=1;
            vv=(R_trans_mm*R_tx_now_mm*inv(R_rot)*inv(R_tx_now_mm)*vv')';
            etc_render_fsbrain.object.Vertices=vv(:,1:3);


            if(~isempty(app.norm_obj))
                tmp=[app.norm_obj.XData; app.norm_obj.YData; app.norm_obj.ZData; ones(size(app.norm_obj.XData))];
                tmp=(R_trans_mm*R_tx_now_mm*inv(R_rot)*inv(R_tx_now_mm)*tmp);
                %tmp=((inv(R_c)*inv(R_rotz)*R_c)*R_trans_mm*R_tx_now*inv(R_rot)*inv(R_tx_now)*tmp);
                app.norm_obj.XData=tmp(1,:);
                app.norm_obj.YData=tmp(2,:);
                app.norm_obj.ZData=tmp(3,:);
            end;
            
            if(~isempty(app.up_obj))
                tmp=[app.up_obj.XData; app.up_obj.YData; app.up_obj.ZData; ones(size(app.up_obj.XData))];
                tmp=(R_trans_mm*R_tx_now_mm*inv(R_rot)*inv(R_tx_now_mm)*tmp);
                %tmp=((inv(R_c)*inv(R_rotz)*R_c)*R_trans_mm*R_tx_now*inv(R_rot)*inv(R_tx_now)*tmp);
                app.up_obj.XData=tmp(1,:);
                app.up_obj.YData=tmp(2,:);
                app.up_obj.ZData=tmp(3,:);
            end;


            %transform data
            tmp=(R_trans_mm*R_tx_now_mm*inv(R_rot)*inv(R_tx_now_mm)*[etc_render_fsbrain.object.UserData.Origin(:)' 1]')';
            %tmp=((inv(R_c)*inv(R_rotz)*(R_c))*R_trans_mm*R_tx_now*inv(R_rot)*inv(R_tx_now)*[etc_render_fsbrain.object.UserData.Origin(:)' 1]')';
            etc_render_fsbrain.object.UserData.Origin=tmp(1:3);
            fprintf('after moving: coil origin: %s \n',mat2str(etc_render_fsbrain.object.UserData.Origin(:).',3));

            tmp=(inv(R_rot)*[etc_render_fsbrain.object.UserData.Axis(:)' 1]')';
            %tmp=((inv(R_c)*inv(R_rotz)*(R_c))*R_trans_mm*R_tx_now*inv(R_rot)*inv(R_tx_now)*[etc_render_fsbrain.object.UserData.Axis(:)' 1]')';
            etc_render_fsbrain.object.UserData.Axis=tmp(1:3);

            tmp=(inv(R_rot)*[etc_render_fsbrain.object.UserData.Up(:)' 1]')';
            %tmp=((inv(R_c)*inv(R_rotz)*(R_c))*R_trans_mm*R_tx_now*inv(R_rot)*inv(R_tx_now)*[etc_render_fsbrain.object.UserData.Up(:)' 1]')';
            etc_render_fsbrain.object.UserData.Up=tmp(1:3);


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %aligning UP toward +z

            coil_center=etc_render_fsbrain.object.UserData.Origin(:);
            R_c_mm=eye(4);
            R_c_mm(1:3,4)=-coil_center(1:3);
            R_c=R_c_mm;
            R_c(1:3,4)=R_c(1:3,4)./1e3;

            coil_axis=etc_render_fsbrain.object.UserData.Axis(:).';
            tmp=coil_axis./norm(coil_axis);
            x=tmp(1);
            y=tmp(2);
            z=tmp(3);

            X1=etc_render_fsbrain.object.UserData.Origin(1);
            Y1=etc_render_fsbrain.object.UserData.Origin(2);
            Z1=etc_render_fsbrain.object.UserData.Origin(3);

            X2=0;
            Y2=0;
            Z2=100;
            vtop=[X2-X1; Y2-Y1; Z2-Z1]';
            vtop_perp=vtop-sum(vtop.*tmp).*tmp;
           
            vup=etc_render_fsbrain.object.UserData.Up;


            %determine the rotation between two vectors;
            xx = cross(vtop_perp,vup);
            c = sign(dot(xx,etc_render_fsbrain.object.UserData.Axis)) * norm(xx);
            deg = atan2d(c,dot(vtop_perp,vup)).*pi./180;

            %[azimuth,elevation,r] = cart2sph(x,y,z);
            %deg=azimuth;
            theta=(deg);
            r_rotz=[cos(theta)+x*x*(1-cos(theta)), x*y*(1-cos(theta))-z*sin(theta), x*z*(1-cos(theta))+y*sin(theta);
                y*x*(1-cos(theta))+z*sin(theta) cos(theta)+y*y*(1-cos(theta)) y*z*(1-cos(theta))-x*sin(theta);
                z*x*(1-cos(theta))-y*sin(theta) z*y*(1-cos(theta))+x*sin(theta) cos(theta)+z*z*(1-cos(theta))];
            %https://en.wikipedia.org/wiki/Rotation_matrix
            R_rotz=eye(4);
            R_rotz(1:3,1:3)=r_rotz;


            etc_render_fsbrain.object_xfm=inv(R_c)*inv(R_rotz)*(R_c)*etc_render_fsbrain.object_xfm;

            %transform object in visualiation

            vv=etc_render_fsbrain.object.Vertices;
            vv(:,4)=1;
            vv=(inv(R_c_mm)*inv(R_rotz)*(R_c_mm)*vv')';
            etc_render_fsbrain.object.Vertices=vv(:,1:3);


            if(~isempty(app.norm_obj))
                tmp=[app.norm_obj.XData; app.norm_obj.YData; app.norm_obj.ZData; ones(size(app.norm_obj.XData))];
                tmp=((inv(R_c_mm)*inv(R_rotz)*R_c_mm)*tmp);
                %tmp=((inv(R_c)*inv(R_rotz)*R_c)*R_trans_mm*R_tx_now*inv(R_rot)*inv(R_tx_now)*tmp);
                app.norm_obj.XData=tmp(1,:);
                app.norm_obj.YData=tmp(2,:);
                app.norm_obj.ZData=tmp(3,:);
            end;
            
            if(~isempty(app.up_obj))
                tmp=[app.up_obj.XData; app.up_obj.YData; app.up_obj.ZData; ones(size(app.up_obj.XData))];
                tmp=((inv(R_c_mm)*inv(R_rotz)*R_c_mm)*tmp);
                %tmp=((inv(R_c)*inv(R_rotz)*R_c)*R_trans_mm*R_tx_now*inv(R_rot)*inv(R_tx_now)*tmp);
                app.up_obj.XData=tmp(1,:);
                app.up_obj.YData=tmp(2,:);
                app.up_obj.ZData=tmp(3,:);
            end;


            %transform data
            tmp=((inv(R_c_mm)*inv(R_rotz)*R_c_mm)*[etc_render_fsbrain.object.UserData.Origin(:)' 1]')';
            %tmp=((inv(R_c)*inv(R_rotz)*(R_c))*R_trans_mm*R_tx_now*inv(R_rot)*inv(R_tx_now)*[etc_render_fsbrain.object.UserData.Origin(:)' 1]')';
            etc_render_fsbrain.object.UserData.Origin=tmp(1:3);

            tmp=(inv(R_rotz)*[etc_render_fsbrain.object.UserData.Axis(:)' 1]')';
            %tmp=((inv(R_c)*inv(R_rotz)*(R_c))*R_trans_mm*R_tx_now*inv(R_rot)*inv(R_tx_now)*[etc_render_fsbrain.object.UserData.Axis(:)' 1]')';
            etc_render_fsbrain.object.UserData.Axis=tmp(1:3);

            tmp=(inv(R_rotz)*[etc_render_fsbrain.object.UserData.Up(:)' 1]')';
            %tmp=((inv(R_c)*inv(R_rotz)*(R_c))*R_trans_mm*R_tx_now*inv(R_rot)*inv(R_tx_now)*[etc_render_fsbrain.object.UserData.Up(:)' 1]')';
            etc_render_fsbrain.object.UserData.Up=tmp(1:3);

            fprintf('After moving: coil origin target: %s \n',mat2str(etc_render_fsbrain.object.UserData.Origin(:).',3));
            app.TextArea.Value{end+1}=sprintf('After moving: coil origin target: %s \n',mat2str(etc_render_fsbrain.object.UserData.Origin(:).',3));
            
            %end of aligning UP toward +z
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            app.rotatedegSlider.Value=0;
            app.offsetmmSlider.Value=0;
            app.tilthorizdegSlider.Value=0;
            app.tiltvertdegSlider.Value=0;
            
            app.coil_center_no_tune=etc_render_fsbrain.object.UserData.Origin;

        end

        % Button pushed function: LoadcoilButton
        function LoadcoilButtonPushed(app, event)
            global etc_render_fsbrain;

            [filename, pathname, filterindex] = uigetfile({'*.mat','TMS coil mat'});
            if(filename==0) return; end;

            Coil = load(sprintf('%s%s',pathname,filename));

            try
                if(isfield(Coil,'P')&isfield(Coil,'t')) app.CoilLamp.Color='g'; else return; end;

                Coil.P=Coil.P.*1e3; %in mm
                figure(etc_render_fsbrain.fig_brain);
                coil_patch=patch('vertices',Coil.P,'faces',Coil.t,'edgecolor','none','facecolor',[ 0.9294    0.6941    0.1255]);
                coil_patch.UserData.Origin=[0 0 0]'; %origin of the coil at +z = 0 mm
                coil_patch.UserData.Axis=[0 0 -1]'; %directional vector; coil plane normal
                coil_patch.UserData.Up=[0 1 0]'; %directional vector; coil upward

                if(isfield(etc_render_fsbrain,'object'))
                    if(~isempty(etc_render_fsbrain.object))
                        try
                            delete(etc_render_fsbrain.object);
                        catch
                        end;
                        
                        etc_render_fsbrain.object=[];
                        etc_render_fsbrain.object_xfm=[];
                    end;
                end;

                etc_render_fsbrain.object=coil_patch;

                if(~isempty(coil_patch))
                    etc_render_fsbrain.object_Vertices_orig=etc_render_fsbrain.object.Vertices;
                    %if(~isempty(object_xfm))
                    %    etc_render_fsbrain.object_xfm=object_xfm;
                    %else
                        etc_render_fsbrain.object_xfm=eye(4);
                    %end;

                    if(isfield(etc_render_fsbrain.object.UserData,'Origin'))
                        etc_render_fsbrain.object.UserData.Origin_orig=etc_render_fsbrain.object.UserData.Origin;
                    end;
                    if(isfield(etc_render_fsbrain.object.UserData,'Axis'))
                        etc_render_fsbrain.object.UserData.Axis_orig=etc_render_fsbrain.object.UserData.Axis;
                    end;
                    if(isfield(etc_render_fsbrain.object.UserData,'Up'))
                        etc_render_fsbrain.object.UserData.Up_orig=etc_render_fsbrain.object.UserData.Up;
                    end;


                    if(isempty(app.up_obj))
                    else
                        delete(app.up_obj);
                    end;
                    X1=etc_render_fsbrain.object.UserData.Origin(1);
                    Y1=etc_render_fsbrain.object.UserData.Origin(2);
                    Z1=etc_render_fsbrain.object.UserData.Origin(3);

                    X2=etc_render_fsbrain.object.UserData.Origin(1)+etc_render_fsbrain.object.UserData.Up(1).*100; %100 mm line
                    Y2=etc_render_fsbrain.object.UserData.Origin(2)+etc_render_fsbrain.object.UserData.Up(2).*100; %100 mm line
                    Z2=etc_render_fsbrain.object.UserData.Origin(3)+etc_render_fsbrain.object.UserData.Up(3).*100; %100 mm line
                    object_line=[X1 X2; Y1 Y2; Z1 Z2]';

                    app.up_obj=plot3(object_line(:, 1), object_line(:, 2), object_line(:, 3), '-g', 'lineWidth', 2);

                    flag_show_up=1;
                    app.VecUpCheckBox.Value=1;

                    if(isempty(app.norm_obj))
                    else
                        delete(app.norm_obj);
                    end;
                    X1=etc_render_fsbrain.object.UserData.Origin(1);
                    Y1=etc_render_fsbrain.object.UserData.Origin(2);
                    Z1=etc_render_fsbrain.object.UserData.Origin(3);

                    X2=etc_render_fsbrain.object.UserData.Origin(1)+etc_render_fsbrain.object.UserData.Axis(1).*100; %100 mm line
                    Y2=etc_render_fsbrain.object.UserData.Origin(2)+etc_render_fsbrain.object.UserData.Axis(2).*100; %100 mm line
                    Z2=etc_render_fsbrain.object.UserData.Origin(3)+etc_render_fsbrain.object.UserData.Axis(3).*100; %100 mm line
                    object_line=[X1 X2; Y1 Y2; Z1 Z2]';

                    app.norm_obj=plot3(object_line(:, 1), object_line(:, 2), object_line(:, 3), '-m', 'lineWidth', 5);

                    flag_show_norm=1;
                    app.VecNormCheckBox.Value=1;


                    app.CoilShowCheckBox.Value=1;
                    
                else
                    etc_render_fsbrain.object_xfm=[];
                    if(isfield(etc_render_fsbrain.object.UserData,'Origin'))
                        etc_render_fsbrain.object.UserData.Origin_orig=etc_render_fsbrain.object.UserData.Origin;
                    end;
                    if(isfield(etc_render_fsbrain.object.UserData,'Axis'))
                        etc_render_fsbrain.object.UserData.Axis_orig=etc_render_fsbrain.object.UserData.Axis;
                    end;
                    if(isfield(etc_render_fsbrain.object.UserData,'Up'))
                        etc_render_fsbrain.object.UserData.Up_orig=etc_render_fsbrain.object.UserData.Up;
                    end;
                end;


                app.rotatedegSlider.Value=0;
                app.offsetmmSlider.Value=0;
                app.tilthorizdegSlider.Value=0;
                app.tiltvertdegSlider.Value=0;
                app.coil_center_no_tune=etc_render_fsbrain.object.UserData.Origin;

                app.TextArea.Value{end+1}=sprintf('Variable Coil successfully loaded!\n');
                

            catch
                fprintf('Error in loading coil MAT file [%s]!\n',sprintf('%s%s',pathname,filename));

                app.TextArea.Value{end+1}=sprintf('Error in loading coil MAT file [%s]!\n',sprintf('%s%s',pathname,filename));
            
            end;

        end

        % Button pushed function: SButton
        function SButtonPushed(app, event)
            etc_render_fsbrain_handle('kb','cc','S');
        end

        % Button pushed function: xButton
        function xButtonPushed(app, event)
            etc_render_fsbrain_handle('kb','cc','x');
        end

        % Button pushed function: LoadstrcoilButton
        function LoadstrcoilButtonPushed(app, event)
            global etc_render_fsbrain;

            [filename, pathname, filterindex] = uigetfile({'*.mat','TMS coil mat'});
            if(filename==0) return; end;

            Coil = load(sprintf('%s%s',pathname,filename));

            try
                if(isfield(Coil,'strcoil'))
                    tmp=Coil.strcoil.Pwire.*1e3;
                    tmp(:,end+1)=1;
                    tmp=tmp';
                    xfm=etc_render_fsbrain.object_xfm;
                    xfm(1:3,4)=xfm(1:3,4).*1e3; %into mm
                    tmp=xfm*tmp;

                    %remember this to be used for updating later
                    app.strcoil_obj_xfm=etc_render_fsbrain.object_xfm;

                    if(~isempty(app.strcoil_obj))
                        if(isvalid(app.strcoil_obj))
                            try
                                delete(app.strcoil_obj);
                            catch
                            end;
                        end;
                    end;
                    app.strcoil_obj=patch('faces', Coil.strcoil.Ewire, 'vertices', tmp(1:3,:)', 'EdgeColor', 'g', 'FaceColor','b');
                 
                    assignin('base','strcoil',Coil.strcoil);
                    fprintf('variable [strcoil] loaded to the workspace.\n');
                    app.TextArea.Value{end+1}='variable [strcoil] loaded to the workspace.';
                    app.StrcoilLamp.Color='g';
                    app.StrcoilShowCheckBox.Value=1;

%                     app.rotatedegSlider.Value=0;
%                     app.offsetmmSlider.Value=0;
%                     app.tilthorizdegSlider.Value=0;
%                     app.tiltvertdegSlider.Value=0;
%                     app.coil_center_no_tune=[];


                else
                    fprintf('No variable [strcoil] loaded to the workspace!\n');
                    app.TextArea.Value{end+1}='No variable [strcoil] loaded to the workspace!\n';
                    
                    return;
                end;
            catch
                fprintf('Error in loading strcoil MAT file [%s]!\n',sprintf('%s%s',pathname,filename));
                app.TextArea.Value{end+1}='Error in loading strcoil MAT file [%s]!\n';
            end;
        end

        % Value changed function: VecNormCheckBox
        function VecNormCheckBoxValueChanged(app, event)
            value = app.VecNormCheckBox.Value;
            
            global etc_render_fsbrain;

            app.flag_show_norm=value;
            if(value)
                if(isempty(app.norm_obj))
                    X1=etc_render_fsbrain.object.UserData.Origin(1);
                    Y1=etc_render_fsbrain.object.UserData.Origin(2);
                    Z1=etc_render_fsbrain.object.UserData.Origin(3);

                    X2=etc_render_fsbrain.object.UserData.Origin(1)+etc_render_fsbrain.object.UserData.Axis(1).*100; %100 mm line
                    Y2=etc_render_fsbrain.object.UserData.Origin(2)+etc_render_fsbrain.object.UserData.Axis(2).*100; %100 mm line
                    Z2=etc_render_fsbrain.object.UserData.Origin(3)+etc_render_fsbrain.object.UserData.Axis(3).*100; %100 mm line
                    object_line=[X1 X2; Y1 Y2; Z1 Z2]';

                    app.norm_obj=plot3(object_line(:, 1), object_line(:, 2), object_line(:, 3), '-m', 'lineWidth', 5);
                else
                    set(app.norm_obj,'visible','on');
                end;
            else
                if(~isempty(app.norm_obj))
                    set(app.norm_obj,'visible','off');
                end;
            end;
        end

        % Value changed function: VecUpCheckBox
        function VecUpCheckBoxValueChanged(app, event)
            value = app.VecUpCheckBox.Value;
            
            global etc_render_fsbrain;

            app.flag_show_up=value;
            if(value)
                if(isempty(app.up_obj))
                    X1=etc_render_fsbrain.object.UserData.Origin(1);
                    Y1=etc_render_fsbrain.object.UserData.Origin(2);
                    Z1=etc_render_fsbrain.object.UserData.Origin(3);

                    X2=etc_render_fsbrain.object.UserData.Origin(1)+etc_render_fsbrain.object.UserData.Up(1).*100; %100 mm line
                    Y2=etc_render_fsbrain.object.UserData.Origin(2)+etc_render_fsbrain.object.UserData.Up(2).*100; %100 mm line
                    Z2=etc_render_fsbrain.object.UserData.Origin(3)+etc_render_fsbrain.object.UserData.Up(3).*100; %100 mm line
                    object_line=[X1 X2; Y1 Y2; Z1 Z2]';

                    app.up_obj=plot3(object_line(:, 1), object_line(:, 2), object_line(:, 3), '-g', 'lineWidth', 2);
                else
                    set(app.up_obj,'visible','on');
                end;
            else
                if(~isempty(app.up_obj))
                    set(app.up_obj,'visible','off');
                end;
            end;

        end

        % Value changed function: rotatedegSlider
        function rotatedegSliderValueChanged(app, event)
            value = app.rotatedegSlider.Value;

            if(norm(app.CoilLamp.Color-[1 0 0])<eps) 
                app.rotatedegSlider.Value=0;
                return; 
            end;

            app.rotatedegEditField.Value=value;

            tune_rotate(app);
        end

        % Value changed function: tiltvertdegSlider
        function tiltvertdegSliderValueChanged(app, event)
            value = app.tiltvertdegSlider.Value;
            
            if(norm(app.CoilLamp.Color-[1 0 0])<eps) 
                app.tiltvertdegSlider.Value=0;
                return; 
            end;
            
            app.vertdegEditField.Value=value;

            tune_vert(app);
        end

        % Value changed function: offsetmmSlider
        function offsetmmSliderValueChanged(app, event)
            value = app.offsetmmSlider.Value;

            if(norm(app.CoilLamp.Color-[1 0 0])<eps) 
                app.offsetmmSlider.Value=0;
                return; 
            end;
            
            app.offsetmmEditField.Value=value;

            tune_offset(app);
        end

        % Value changed function: tilthorizdegSlider
        function tilthorizdegSliderValueChanged(app, event)
            value = app.tilthorizdegSlider.Value;
    
            if(norm(app.CoilLamp.Color-[1 0 0])<eps) 
                app.tilthorizdegSlider.Value=0;
                return; 
            end;

            app.horizdegEditField.Value=value;

            tune_horiz(app);
        end

        % Value changed function: vertdegEditField
        function vertdegEditFieldValueChanged(app, event)
            value = app.vertdegEditField.Value;
            
            if(norm(app.CoilLamp.Color-[1 0 0])<eps) 
                app.vertdegEditField.Value=0;
                return; 
            end;

            app.tiltvertdegSlider.Value=value;

            tune_vert(app);
        end

        % Value changed function: horizdegEditField
        function horizdegEditFieldValueChanged(app, event)
            value = app.horizdegEditField.Value;
  
            if(norm(app.CoilLamp.Color-[1 0 0])<eps) 
                app.horizdegEditField.Value=0;
                return; 
            end;

            app.tilthorizdegSlider.Value=value;

            tune_horiz(app);            
        end

        % Value changed function: offsetmmEditField
        function offsetmmEditFieldValueChanged(app, event)
            value = app.offsetmmEditField.Value;
            
            if(norm(app.CoilLamp.Color-[1 0 0])<eps) 
                app.offsetmmEditField.Value=0;
                return; 
            end;
            
            app.offsetmmSlider.Value=value;

            tune_offset(app);               
        end

        % Value changed function: rotatedegEditField
        function rotatedegEditFieldValueChanged(app, event)
            value = app.rotatedegEditField.Value;

            if(norm(app.CoilLamp.Color-[1 0 0])<eps) 
                app.rotatedegEditField.Value=0;
                return; 
            end;
            
            app.rotatedegSlider.Value=value;

            tune_rotate(app);
        end

        % Value changed function: CoilShowCheckBox
        function CoilShowCheckBoxValueChanged(app, event)
            value = app.CoilShowCheckBox.Value;
            
            global etc_render_fsbrain;

            if(~value)
                if(isfield(etc_render_fsbrain,'object'))
                    etc_render_fsbrain.object.Visible='off';
                end;
            else
                if(isfield(etc_render_fsbrain,'object'))
                    etc_render_fsbrain.object.Visible='on';
                end;                
            end;
        end

        % Value changed function: StrcoilShowCheckBox
        function StrcoilShowCheckBoxValueChanged(app, event)
            value = app.StrcoilShowCheckBox.Value;
            
            global etc_render_fsbrain;

            if(~value)
                if(~isempty(app.strcoil_obj))
                    app.strcoil_obj.Visible='off';
                end;
            else
                if(~isempty(app.strcoil_obj))
                    app.strcoil_obj.Visible='on';
                end;                
            end;
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            tms_nav_object=[];

            global etc_render_fsbrain;

            fn=sprintf('')
            save(fn,'tms_nav_object');


        end

        % Button pushed function: UpdatestrcoilButton
        function UpdatestrcoilButtonPushed(app, event)
            global etc_render_fsbrain;

            try
                tmp=app.strcoil_obj.Vertices;
                tmp(:,end+1)=1;
                tmp=tmp';
                xfm=etc_render_fsbrain.object_xfm;
                xfm(1:3,4)=xfm(1:3,4).*1e3; %into mm
                xfm_tmp=app.strcoil_obj_xfm;
                xfm_tmp(1:3,4)=xfm_tmp(1:3,4)*1e3; %into mm
                tmp=xfm*inv(xfm_tmp)*tmp;

                app.strcoil_obj.Vertices=tmp(1:3,:)'; %update
                app.strcoil_obj_xfm=etc_render_fsbrain.object_xfm;
                
                tmp=app.strcoil_obj.Vertices;
                assignin('base','tmp',tmp);
                evalin('base','strcoil.Pwire=tmp;');
                fprintf('variable [strcoil] update at the workspace.\n');
                app.TextArea.Value{end+1}='variable [strcoil] updated at the workspace.';
                app.StrcoilLamp.Color='g';
                app.StrcoilShowCheckBox.Value=1;

            catch
                fprintf('Error in updating strcoil!\n');
                app.TextArea.Value{end+1}='Error in updating strcoil!\n';
            end;


        end

        % Button pushed function: CalcButton
        function CalcButtonPushed(app, event)
            

             global etc_render_fsbrain;

             status=etc_tms_efield_surf(varargin);
        end

        % Value changed function: SurfaceDropDown
        function SurfaceDropDownValueChanged(app, event)
            

        end

        % Button pushed function: prepareheadmodelButton
        function prepareheadmodelButtonPushed(app, event)

            try
                [status, t, P, normals, Cener, Area, Indicator, name, tissue, cond, enclosingTissueIdx, condin, condout, contrast, tneighbor, RnumberE, ineighborE, EC] =etc_tms_efield_prep_model('flag_display',1,'flag_save',1);
                app.PrepModelLamp.Color='g';
            catch

            end;

        end

        % Callback function: not associated with a component
        function makeheadmodelButtonPushed(app, event)
            try     
                

                app.MakeModelLamp.Color='g';
            catch

            end;
            
        end

        % Value changed function: HeadModelDropDown
        function HeadModelDropDownValueChanged(app, event)
            value = app.HeadModelDropDown.Value;
            
            switch value
                case 'Select the model file'
                    [filename, pathname, filterindex] = uigetfile({'*.*','model file (tissue_index.txt)'});
                    if(filename==0) return; end;
                    
                    if(exist(sprintf('%s%s',pathname,filename),'file'))
                        try
                            [name, tissue, cond, enclosingTissueIdx] = tissue_index_read(sprintf('%s%s',pathname,filename));
                        catch 
                        end;
                    end;
                    app.MakeModelLamp.Color='g';
                    
                case 'Make the model file'
                    try
                        global etc_render_fsbrain;


                        for f_idx=1:length(etc_render_fsbrain.surf_obj)
                            vv=[];ff=[];
                            %save files for e-field modeling
                            P=etc_render_fsbrain.surf_obj(f_idx).vertex;
                            t=etc_render_fsbrain.surf_obj(f_idx).face;
                            normals=etc_render_fsbrain.surf_obj(f_idx).surf_norm;
                            [tmp,fstem]=fileparts(etc_render_fsbrain.surf_obj(f_idx).filename);
                            fn=sprintf('%s_%s',etc_render_fsbrain.subject,fstem)
                            save(sprintf('%s',fn),'P','t','normals');
                        end;

                        app.MakeModelLamp.Color='g';
                    catch

                    end;
                otherwise
                    app.MakeModelLamp.Color='r';
            end;
        end

        % Cell selection callback: UITissueTable
        function UITissueTableCellSelection(app, event)
            indices = event.Indices;
            
            fprintf('clicked!\n')
        end

        % Clicked callback: UITissueTable
        function UITissueTableClicked(app, event)


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TMSNavFigure = uifigure('Visible', 'off');
            app.TMSNavFigure.Name = 'MATLAB App';
            app.TMSNavFigure.CloseRequestFcn = createCallbackFcn(app, @TMSNavFigureCloseRequest, true);
            app.ScalpPanel = uipanel(app.TMSNavFigure);
            app.ScalpPanel.Title = 'Scalp';
            app.ShowscalpCheckBox = uicheckbox(app.ScalpPanel);
            app.ShowscalpCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowscalpCheckBoxValueChanged, true);
            app.ShowscalpCheckBox.Text = '';
            app.surfaceDropDownLabel = uilabel(app.ScalpPanel);
            app.surfaceDropDownLabel.Text = 'surface';
            app.SurfaceDropDown = uidropdown(app.ScalpPanel);
            app.SurfaceDropDown.ValueChangedFcn = createCallbackFcn(app, @SurfaceDropDownValueChanged, true);
            app.SButton = uibutton(app.ScalpPanel, 'push');
            app.SButton.ButtonPushedFcn = createCallbackFcn(app, @SButtonPushed, true);
            app.SButton.Text = 'S';
            app.TMStargetPanel = uipanel(app.TMSNavFigure);
            app.TMStargetPanel.Title = 'TMS target';
            app.GoButton = uibutton(app.TMStargetPanel, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.MNIEditField_2Label = uilabel(app.TMStargetPanel);
            app.MNIEditField_2Label.Text = 'MNI';
            app.MNIEditField = uieditfield(app.TMStargetPanel, 'text');
            app.MNIEditField.ValueChangedFcn = createCallbackFcn(app, @MNIEditFieldValueChanged, true);
            app.CRSEditFieldLabel = uilabel(app.TMStargetPanel);
            app.CRSEditFieldLabel.Text = 'CRS';
            app.CRSEditField = uieditfield(app.TMStargetPanel, 'text');
            app.CRSEditField.ValueChangedFcn = createCallbackFcn(app, @CRSEditFieldValueChanged, true);
            app.XYZEditFieldLabel = uilabel(app.TMStargetPanel);
            app.XYZEditFieldLabel.Text = 'XYZ';
            app.XYZEditField = uieditfield(app.TMStargetPanel, 'text');
            app.XYZEditField.ValueChangedFcn = createCallbackFcn(app, @XYZEditFieldValueChanged, true);
            app.vertexindexEditFieldLabel = uilabel(app.TMStargetPanel);
            app.vertexindexEditFieldLabel.Text = 'vertex index';
            app.vertexindexEditField = uieditfield(app.TMStargetPanel, 'text');
            app.vertexindexEditField.ValueChangedFcn = createCallbackFcn(app, @vertexindexEditFieldValueChanged, true);
            app.LoadcoilButton = uibutton(app.TMStargetPanel, 'push');
            app.LoadcoilButton.ButtonPushedFcn = createCallbackFcn(app, @LoadcoilButtonPushed, true);
            app.LoadcoilButton.Text = 'Load coil';
            app.xButton = uibutton(app.TMStargetPanel, 'push');
            app.xButton.ButtonPushedFcn = createCallbackFcn(app, @xButtonPushed, true);
            app.xButton.Text = 'x';
            app.CoilLamp = uilamp(app.TMStargetPanel);
            app.VecNormCheckBox = uicheckbox(app.TMStargetPanel);
            app.VecNormCheckBox.ValueChangedFcn = createCallbackFcn(app, @VecNormCheckBoxValueChanged, true);
            app.VecNormCheckBox.Text = 'norm.';
            app.VecUpCheckBox = uicheckbox(app.TMStargetPanel);
            app.VecUpCheckBox.ValueChangedFcn = createCallbackFcn(app, @VecUpCheckBoxValueChanged, true);
            app.VecUpCheckBox.Text = 'up';
            app.tiltvertdegSliderLabel = uilabel(app.TMStargetPanel);
            app.tiltvertdegSliderLabel.Text = {'tilt vert.'; '(deg)'};
            app.tiltvertdegSlider = uislider(app.TMStargetPanel);
            app.tiltvertdegSlider.Limits = [-30 30];
            app.tiltvertdegSlider.ValueChangedFcn = createCallbackFcn(app, @tiltvertdegSliderValueChanged, true);
            app.tilthorizdegSliderLabel = uilabel(app.TMStargetPanel);
            app.tilthorizdegSliderLabel.Text = {'tilt horiz.'; '(deg)'};
            app.tilthorizdegSlider = uislider(app.TMStargetPanel);
            app.tilthorizdegSlider.Limits = [-30 30];
            app.tilthorizdegSlider.ValueChangedFcn = createCallbackFcn(app, @tilthorizdegSliderValueChanged, true);
            app.offsetmmSliderLabel = uilabel(app.TMStargetPanel);
            app.offsetmmSliderLabel.Text = {'offset'; '(mm)'};
            app.offsetmmSlider = uislider(app.TMStargetPanel);
            app.offsetmmSlider.Limits = [0 20];
            app.offsetmmSlider.ValueChangedFcn = createCallbackFcn(app, @offsetmmSliderValueChanged, true);
            app.rotatedegSliderLabel = uilabel(app.TMStargetPanel);
            app.rotatedegSliderLabel.Text = {'rotate'; '(deg)'};
            app.rotatedegSlider = uislider(app.TMStargetPanel);
            app.rotatedegSlider.Limits = [-180 180];
            app.rotatedegSlider.ValueChangedFcn = createCallbackFcn(app, @rotatedegSliderValueChanged, true);
            app.CoilShowCheckBox = uicheckbox(app.TMStargetPanel);
            app.CoilShowCheckBox.ValueChangedFcn = createCallbackFcn(app, @CoilShowCheckBoxValueChanged, true);
            app.CoilShowCheckBox.Text = '';
            app.vertdegEditField = uieditfield(app.TMStargetPanel, 'numeric');
            app.vertdegEditField.ValueChangedFcn = createCallbackFcn(app, @vertdegEditFieldValueChanged, true);
            app.horizdegEditField = uieditfield(app.TMStargetPanel, 'numeric');
            app.horizdegEditField.ValueChangedFcn = createCallbackFcn(app, @horizdegEditFieldValueChanged, true);
            app.offsetmmEditField = uieditfield(app.TMStargetPanel, 'numeric');
            app.offsetmmEditField.ValueChangedFcn = createCallbackFcn(app, @offsetmmEditFieldValueChanged, true);
            app.rotatedegEditField = uieditfield(app.TMStargetPanel, 'numeric');
            app.rotatedegEditField.ValueChangedFcn = createCallbackFcn(app, @rotatedegEditFieldValueChanged, true);
            app.StrcoilPanel = uipanel(app.TMSNavFigure);
            app.StrcoilPanel.Title = 'Strcoil';
            app.LoadstrcoilButton = uibutton(app.StrcoilPanel, 'push');
            app.LoadstrcoilButton.ButtonPushedFcn = createCallbackFcn(app, @LoadstrcoilButtonPushed, true);
            app.LoadstrcoilButton.Text = 'Load strcoil';
            app.StrcoilLamp = uilamp(app.StrcoilPanel);
            app.StrcoilShowCheckBox = uicheckbox(app.StrcoilPanel);
            app.StrcoilShowCheckBox.ValueChangedFcn = createCallbackFcn(app, @StrcoilShowCheckBoxValueChanged, true);
            app.StrcoilShowCheckBox.Text = '';
            app.UpdatestrcoilButton = uibutton(app.StrcoilPanel, 'push');
            app.UpdatestrcoilButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatestrcoilButtonPushed, true);
            app.UpdatestrcoilButton.Text = 'update';
            app.ExportButton = uibutton(app.TMSNavFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.ImportButton = uibutton(app.TMSNavFigure, 'push');
            app.ImportButton.Text = 'Import';
            app.TextArea = uitextarea(app.TMSNavFigure);
            app.EfieldPanel = uipanel(app.TMSNavFigure);
            app.EfieldPanel.Title = 'E-field';
            app.CalcButton = uibutton(app.EfieldPanel, 'push');
            app.CalcButton.ButtonPushedFcn = createCallbackFcn(app, @CalcButtonPushed, true);
            app.CalcButton.Text = 'Calc.';
            app.MakeModelLamp = uilamp(app.EfieldPanel);
            app.prepareheadmodelButton = uibutton(app.EfieldPanel, 'push');
            app.prepareheadmodelButton.ButtonPushedFcn = createCallbackFcn(app, @prepareheadmodelButtonPushed, true);
            app.prepareheadmodelButton.Text = 'prepare head model';
            app.PrepModelLamp = uilamp(app.EfieldPanel);
            app.headmodelLabel = uilabel(app.EfieldPanel);
            app.headmodelLabel.Text = 'head model';
            app.HeadModelDropDown = uidropdown(app.EfieldPanel);
            app.HeadModelDropDown.Items = {'Choose from one below', 'Make the model file', 'Select the model file'};
            app.HeadModelDropDown.ValueChangedFcn = createCallbackFcn(app, @HeadModelDropDownValueChanged, true);
            app.HeadModelDropDown.Value = 'Choose from one below';
            app.UITissueTable = uitable(app.EfieldPanel);
            app.UITissueTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITissueTable.RowName = {};
            app.UITissueTable.CellSelectionCallback = createCallbackFcn(app, @UITissueTableCellSelection, true);
            app.UITissueTable.ClickedFcn = createCallbackFcn(app, @UITissueTableClicked, true);
            app.TissueAddButton = uibutton(app.EfieldPanel, 'push');
            app.TissueAddButton.Text = 'Add';
            % Show the figure after all components are created
            app.TMSNavFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = etc_render_fsbrain_tms_nav

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TMSNavFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TMSNavFigure)
        end
    end
end