classdef etc_trace_analyze < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AnalyzeUIFigure            matlab.ui.Figure
        DataDropDown               matlab.ui.control.DropDown
        DatatobeprocesssedDropDownLabel  matlab.ui.control.Label
        FilterPanel                matlab.ui.container.Panel
        LPEditField                matlab.ui.control.NumericEditField
        LowpassHzEditFieldLabel    matlab.ui.control.Label
        filterButton               matlab.ui.control.Button
        HPEditField                matlab.ui.control.NumericEditField
        HighpassHzLabel            matlab.ui.control.Label
        ExportButton               matlab.ui.control.Button
        AddButton                  matlab.ui.control.Button
        ComponentsPanel            matlab.ui.container.Panel
        discardcompEditField       matlab.ui.control.NumericEditField
        discardcompEditFieldLabel  matlab.ui.control.Label
        ReconstructButton          matlab.ui.control.Button
        NcompEditField             matlab.ui.control.NumericEditField
        ofcompEditFieldLabel       matlab.ui.control.Label
        AnalyzeButton              matlab.ui.control.Button
    end

    
    properties (Access = public)
        data_now; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global etc_trace_obj;

            app.data_now=etc_trace_obj.data;

            app.DataDropDown.Items=etc_trace_obj.all_data_name;
            app.DataDropDown.Value=etc_trace_obj.all_data_name{etc_trace_obj.all_data_main_idx};

            app.NcompEditField.Value=round(size(etc_trace_obj.data,1)./3);

            app.ReconstructButton.Enable='off';
            app.discardcompEditField.Enable='off';
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            global etc_trace_obj;

            numOfIC=app.NcompEditField.Value;
            try
                if(numOfIC<1 | numOfIC> size(etc_trace_obj.data)) fprintf('error in specifiying NumOfIC!\n'); return; end;

                [icasig, A, W] = fastica (etc_trace_obj.data,'numOfIC',numOfIC,'g','tanh');

                n_old_component=0;
                if(isfield(etc_trace_obj,'data_component'))
                    n_old_component=size(etc_trace_obj.data_component{etc_trace_obj.all_data_main_idx},1);
                end;
                etc_trace_obj.data_component{etc_trace_obj.all_data_main_idx}=icasig;
                etc_trace_obj.topo_component{etc_trace_obj.all_data_main_idx}=A;

                ch_names={};
                for idx=1:size(etc_trace_obj.data_component{etc_trace_obj.all_data_main_idx},1)
                    ch_names{idx}=sprintf('comp%03d',idx);
                end;
                etc_trace_obj.data_component_ch_names{etc_trace_obj.all_data_main_idx}=ch_names;

                str=etc_trace_obj.montage_ch_name{etc_trace_obj.montage_idx}.ch_names;
                obj=findobj('Tag','listbox_channel');
                str=get(obj,'String');
                str(end:-1:end-n_old_component+1)=[];
                if(isfield(etc_trace_obj,'data_component_ch_names'))
                    set(obj,'String',cat(1,str(:),etc_trace_obj.data_component_ch_names{etc_trace_obj.all_data_main_idx}(:)));
                end;

                etc_trace_obj.flag_data_component=1;

                obj=findobj('Tag','checkbox_topo_component');
                set(obj,'Enable','On');
                set(obj,'Value',1);

                app.ReconstructButton.Enable='on';
                app.discardcompEditField.Enable='on';


                etc_trace_handle('redraw');
                %app.discardcompEditField.Value=[];
            catch
                fprintf('error in data decomposition...\n');
                return;
            end;
        end

        % Button pushed function: ReconstructButton
        function ReconstructButtonPushed(app, event)
            
        end

        % Button pushed function: filterButton
        function filterButtonPushed(app, event)
            global etc_trace_obj

            hp=app.HPEditField.Value;
            lp=app.LPEditField.Value;
            
            if(hp<lp)
                for ch_idx=1:size(etc_trace_obj.data)
                    app.data_now(ch_idx,:)=bandpass(etc_trace_obj.data(ch_idx,:),[hp lp],etc_trace_obj.fs)
                end;
            end;
        end

        % Value changed function: DataDropDown
        function DataDropDownValueChanged(app, event)
            value = app.DataDropDown.Value;
            
            global etc_trace_obj;

            app.data_now=etc_trace_obj.etc_trace_obj.all_data{value};
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AnalyzeUIFigure = uifigure('Visible', 'off');
            app.AnalyzeUIFigure.Name = 'Analyze';
            app.ComponentsPanel = uipanel(app.AnalyzeUIFigure);
            app.ComponentsPanel.Title = 'Components';
            app.AnalyzeButton = uibutton(app.ComponentsPanel, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.ofcompEditFieldLabel = uilabel(app.ComponentsPanel);
            app.ofcompEditFieldLabel.Text = '# of comp.';
            app.NcompEditField = uieditfield(app.ComponentsPanel, 'numeric');
            app.ReconstructButton = uibutton(app.ComponentsPanel, 'push');
            app.ReconstructButton.ButtonPushedFcn = createCallbackFcn(app, @ReconstructButtonPushed, true);
            app.ReconstructButton.Text = 'Reconstruct';
            app.discardcompEditFieldLabel = uilabel(app.ComponentsPanel);
            app.discardcompEditFieldLabel.Text = 'discard comp';
            app.discardcompEditField = uieditfield(app.ComponentsPanel, 'numeric');
            app.AddButton = uibutton(app.AnalyzeUIFigure, 'push');
            app.AddButton.Text = 'Add';
            app.ExportButton = uibutton(app.AnalyzeUIFigure, 'push');
            app.ExportButton.Text = 'Export';
            app.FilterPanel = uipanel(app.AnalyzeUIFigure);
            app.FilterPanel.Title = 'Filter';
            app.HighpassHzLabel = uilabel(app.FilterPanel);
            app.HighpassHzLabel.Text = 'High-pass (Hz)';
            app.HPEditField = uieditfield(app.FilterPanel, 'numeric');
            app.filterButton = uibutton(app.FilterPanel, 'push');
            app.filterButton.ButtonPushedFcn = createCallbackFcn(app, @filterButtonPushed, true);
            app.filterButton.Text = 'filter';
            app.LowpassHzEditFieldLabel = uilabel(app.FilterPanel);
            app.LowpassHzEditFieldLabel.Text = 'Low-pass (Hz)';
            app.LPEditField = uieditfield(app.FilterPanel, 'numeric');
            app.DatatobeprocesssedDropDownLabel = uilabel(app.AnalyzeUIFigure);
            app.DatatobeprocesssedDropDownLabel.Text = 'Data to be processsed';
            app.DataDropDown = uidropdown(app.AnalyzeUIFigure);
            app.DataDropDown.ValueChangedFcn = createCallbackFcn(app, @DataDropDownValueChanged, true);
            % Show the figure after all components are created
            app.AnalyzeUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = etc_trace_analyze

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AnalyzeUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AnalyzeUIFigure)
        end
    end
end